classdef ModalitiesProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        BrowseButton     matlab.ui.control.Button
        XLabel           matlab.ui.control.Label
        XSliderAxial     matlab.ui.control.Slider
        YLabel           matlab.ui.control.Label
        YSliderAxial     matlab.ui.control.Slider
        XSliderLabel_2   matlab.ui.control.Label
        XSlidercoronal   matlab.ui.control.Slider
        YSliderLabel_2   matlab.ui.control.Label
        YSlidercoronal   matlab.ui.control.Slider
        XSlider_2Label   matlab.ui.control.Label
        XSlidersagittal  matlab.ui.control.Slider
        YSlider_2Label   matlab.ui.control.Label
        YSlidersagittal  matlab.ui.control.Slider
        Line             matlab.ui.control.Button
        Polygon          matlab.ui.control.Button
        Elipse           matlab.ui.control.Button
        Angle            matlab.ui.control.Button
        LengthEditField  matlab.ui.control.EditField
        EditField        matlab.ui.control.EditField
        Axial            matlab.ui.control.UIAxes
        Sagittal         matlab.ui.control.UIAxes
        Coronal          matlab.ui.control.UIAxes
        oblique          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        MriVolume
                %%%%%%%%%%%%%%%%%%
                AxialXvalue = 256 ;
                AxialYvalue = 256;
                %%%%%%%%%%%%%%%%%%%
                SagittalXvalue = 234; %number of imgs
                SagittalYvalue = 117;
                %%%%%%%%%%%%%%%%%%%
                CoronalXvalue = 234;
                CoronalYvalue = 117;
                %%%%%%%%%%%%%%%%%%%
                g_angle = 0;
                g_index = [0 0 0];
                g_normal = [1 1 1];
                

    end


    methods (Access = private)
        
        function  allevents(app,src,evt)
            evname = evt.EventName;
                switch(evname)
                    case{'MovingROI'}
                        disp(['ROI moving Current Position: ' mat2str(evt.CurrentPosition)]);
            
                    case{'ROIMoved'}
                         disp(['ROI moved Current Position: ' mat2str(evt.CurrentPosition)]);
                         A = evt.CurrentPosition;
                         x1 = A(1,1);
                         x2 = A(2,1);
                         y1 = A(1,2);
                         y2 = A(2,2);
                         Xmid = (x2+x1)/2;
                         Ymid = (y2+y1)/2;
                         slope = (y2-y1)/(x2-x1);
                         angle = atand(slope);
                         normal = [y2-y1 x1-x2 0];
                         index = [Xmid Ymid 100];
                         app.g_angle = angle;
                         app.g_normal = normal;
                         app.g_index = index;
%                         slopeNorm = -1/slope;
%                         disp(['Mid point X Co-Ordinate: ' mat2str(Xmid)]);
%                         disp(['Mid point Y Co-Ordinate: ' mat2str(Ymid)]);
%                         disp(['Slope: ' mat2str(slope)]);
%                         disp(['Slope of normal line: ' mat2str(slopeNorm)]);
%                         normLineC = Ymid-(slopeNorm*Xmid);
%                         Xnorm = Xmid+50;
%                         Ynorm = slopeNorm*Xnorm+normLineC;
%                         index = [Xmid Ymid 0];
%                         NormalIndex = [Xnorm Ynorm 0]
%                         Obliqueeshow=(obliqueslice(app.MriVolume,index,NormalIndex,'OutputSize','Full'));
%                         imshow(imrotate(Obliqueeshow,(atand(Ymid/Xmid)-180)),[],'Parent',app.oblique);
                        %imshow(rot90(obliqueslice(app.MriVolume,index,NormalIndex)),[],'Parent',app.oblique);
                        Obliqueeshow=(obliqueslice(app.MriVolume,index,normal,'OutputSize','Full'));
                        imshow(imrotate(Obliqueeshow,(angle-180)),[],'Parent',app.oblique);


                end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
              global V;

                folder = uigetdir;
                disableDefaultInteractivity(app.Axial)
                disableDefaultInteractivity(app.Sagittal)
                disableDefaultInteractivity(app.Coronal)
                disableDefaultInteractivity(app.oblique)
               
                V = dicomreadVolume(folder);                          
                V = squeeze(V); 
                index = [0 0 0]; 
                NormalIndex = [1 1 1]; 
                app.MriVolume = V ;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                imshow(V(:,:,55),[],'Parent' ,app.Axial);
                imshow(rot90(permute(V(:,230,:),[1 3 2])),[],'Parent' ,app.Sagittal);
                imshow(rot90(permute(V(200,:,:),[2 3 1])),[],'Parent' ,app.Coronal); 
                roi=drawline(app.Axial,'Position',[10 10;500 500],'Color','r');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.AxialXvalue = xline(app.Axial,app.AxialXvalue,'w');
                app.AxialYvalue = yline(app.Axial,app.AxialYvalue,'w');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.SagittalXvalue=xline(app.Sagittal,app.SagittalXvalue,'w');
                app.SagittalYvalue = yline(app.Sagittal,app.SagittalYvalue,'w');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.CoronalXvalue = xline(app.Coronal,app.CoronalXvalue,'w');
                app.CoronalYvalue = yline(app.Coronal,app.CoronalYvalue,'w');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                imshow(rot90(obliqueslice(V,index,NormalIndex)),[],'Parent',app.oblique);
                app.g_normal = NormalIndex;
                addlistener(roi,'ROIMoved',@app.allevents);
                roi.DrawingArea=[5,5,500,500];


        end

        % Value changed function: XSliderAxial
        function XSliderAxialValueChanged(app, event)
            mri=app.MriVolume;
            value = app.XSliderAxial.Value;
            app.AxialXvalue.Value = value ;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(rot90(permute(mri(:,round(value),:),[1 3 2])),[],'Parent' ,app.Sagittal); 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.SagittalXvalue = xline(app.Sagittal,234,'w');
            app.SagittalYvalue = yline(app.Sagittal,117,'w');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Value changed function: YSliderAxial
        function YSliderAxialValueChanged(app, event)
            mri=app.MriVolume;
            value = app.YSliderAxial.Value;
            app.AxialYvalue.Value = value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(rot90(permute(mri(round(value),:,:),[2 3 1])),[],'Parent' ,app.Coronal);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.CoronalXvalue = xline(app.Coronal,234,'w');
            app.CoronalYvalue = yline(app.Coronal,117,'w');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            roi=drawline(app.Axial,'Position',[50 50;500 500],'Color','r');
            roi.DrawingArea=[5,5,500,500];


        end

        % Value changed function: XSlidersagittal
        function XSlidersagittalValueChanged(app, event)
            mri=app.MriVolume;
            value = app.XSlidersagittal.Value;
            app.SagittalXvalue.Value=value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(rot90(permute(mri(round(value),:,:),[2 3 1])),[],'Parent' ,app.Coronal); 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.CoronalXvalue = xline(app.Coronal,234,'w');
            app.CoronalYvalue = yline(app.Coronal,117,'w');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Value changed function: YSlidersagittal
        function YSlidersagittalValueChanged(app, event)
           mri=app.MriVolume;
           max=234;
           value = app.YSlidersagittal.Value;
           app.SagittalYvalue.Value=max-value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           imshow(mri(:,:,round(value)),[],'Parent' ,app.Axial);
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           app.AxialXvalue = xline(app.Axial,256,'w');
           app.AxialYvalue = yline(app.Axial,256,'w');    
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           roi=drawline(app.Axial,'Position',[50 50;500 500],'Color','r');
           roi.DrawingArea=[5,5,500,500];


        end

        % Value changed function: XSlidercoronal
        function XSlidercoronalValueChanged(app, event)
            mri=app.MriVolume; 
            value = app.XSlidercoronal.Value;
            app.CoronalXvalue.Value=(value);
            app.CoronalXvalue.Value=round(app.CoronalXvalue.Value);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(rot90(permute(mri(:,round(value),:),[1 3 2])),[],'Parent' ,app.Sagittal);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.SagittalXvalue = xline(app.Sagittal,234,'w');
            app.SagittalYvalue = yline(app.Sagittal,117,'w');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%55
            
        end

        % Value changing function: YSlidercoronal
        function YSlidercoronalValueChanging(app, event)
            
        end

        % Value changed function: YSlidercoronal
        function YSlidercoronalValueChanged(app, event)
            mri=app.MriVolume;
            value = app.YSlidercoronal.Value;
            max=234;
            app.CoronalYvalue.Value=max-value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(mri(:,:,round(value)),[],'Parent' ,app.Axial);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.AxialXvalue = xline(app.Axial,256,'w');
            app.AxialYvalue = yline(app.Axial,256,'w');    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           roi=drawline(app.Axial,'Position',[50 50;500 500],'Color','r');
           roi.DrawingArea=[5,5,500,500];


        end

        % Button pushed function: Line
        function LineButtonPushed(app, event)
            app.EditField.Value='Length: ';
            T_oblique = obliqueslice(app.MriVolume,app.g_index,app.g_normal,'OutputSize','Full');
            imshow(imrotate(T_oblique,(app.g_angle-180)),[],'Parent' ,app.oblique);
            
            lineROI=drawline(app.oblique,'LineWidth',2,'color','r');
            lineROI.InteractionsAllowed='none';
            
            xo1=lineROI.Position(1,1);
            yo1=lineROI.Position(1,2);
            xo2=lineROI.Position(2,1);
            yo2=lineROI.Position(2,2);
           
            length=sqrt((xo1-xo2)^2+(yo1-yo2)^2);
            app.LengthEditField.Value=num2str(length);
        end

        % Button pushed function: Polygon
        function PolygonButtonPushed(app, event)
            app.EditField.Value='Area: ';
            T_oblique = obliqueslice(app.MriVolume,app.g_index,app.g_normal,'OutputSize','Full');
            imshow(imrotate(T_oblique,(app.g_angle-180)),[],'Parent' ,app.oblique);
            
            polygonROI=drawpolygon(app.oblique,'LineWidth',2,'color','r');
            polygonROI.InteractionsAllowed='none';
            
            polygonArea=uint32(area(polyshape(polygonROI.Position)));
            app.LengthEditField.Value=num2str(polygonArea);
        end

        % Button pushed function: Elipse
        function ElipseButtonPushed(app, event)
            app.EditField.Value='Area: ';
            T_oblique = obliqueslice(app.MriVolume,app.g_index,app.g_normal,'OutputSize','Full');
            imshow(imrotate(T_oblique,(app.g_angle-180)),[],'Parent' ,app.oblique);
            
            ellipseROI=drawellipse(app.oblique,'LineWidth',2,'color','r');
            ellipseROI.InteractionsAllowed='none';
            
            ellipseArea=pi*ellipseROI.SemiAxes(1)*ellipseROI.SemiAxes(2);
            app.LengthEditField.Value=num2str(ellipseArea);
        end

        % Button pushed function: Angle
        function AngleButtonPushed(app, event)
            app.EditField.Value='Angle: ';
            T_oblique = obliqueslice(app.MriVolume,app.g_index,app.g_normal,'OutputSize','Full');
            imshow(imrotate(T_oblique,(app.g_angle-180)),[],'Parent' ,app.oblique);
            
            line1ROI=drawline(app.oblique,'LineWidth',2,'color','r');
            line1ROI.InteractionsAllowed='none';
            line2ROI=drawline(app.oblique,'LineWidth',2,'color','r');
            line2ROI.InteractionsAllowed='none';
            
            x11=line1ROI.Position(1,1);
            y11=line1ROI.Position(1,2);
            x12=line1ROI.Position(2,1);
            y12=line1ROI.Position(2,2);
            
            x21=line2ROI.Position(1,1);
            y21=line2ROI.Position(1,2);
            x22=line2ROI.Position(2,1);
            y22=line2ROI.Position(2,2);

            v1 = [x12,y12,0] - [x11,y11,0];
            v2 = [x22,y22,0] - [x21,y21,0];
            Angle1=atan2d(norm(cross(v1, v2)), dot(v1, v2));
            app.LengthEditField.Value=num2str(Angle1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Dicom Viewer';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = {'X'; ''};
            app.XSliderAxial = uislider(app.UIFigure);
            app.XSliderAxial.Limits = [0 512];
            app.XSliderAxial.ValueChangedFcn = createCallbackFcn(app, @XSliderAxialValueChanged, true);
            app.XSliderAxial.Value = 256;
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.YSliderAxial = uislider(app.UIFigure);
            app.YSliderAxial.Limits = [0 512];
            app.YSliderAxial.ValueChangedFcn = createCallbackFcn(app, @YSliderAxialValueChanged, true);
            app.YSliderAxial.Value = 256;
            app.XSliderLabel_2 = uilabel(app.UIFigure);
            app.XSliderLabel_2.Text = {'X'; ''};
            app.XSlidercoronal = uislider(app.UIFigure);
            app.XSlidercoronal.Limits = [0 468];
            app.XSlidercoronal.ValueChangedFcn = createCallbackFcn(app, @XSlidercoronalValueChanged, true);
            app.XSlidercoronal.Value = 234;
            app.YSliderLabel_2 = uilabel(app.UIFigure);
            app.YSliderLabel_2.Text = {'Y'; ''; ''};
            app.YSlidercoronal = uislider(app.UIFigure);
            app.YSlidercoronal.Limits = [0 234];
            app.YSlidercoronal.ValueChangedFcn = createCallbackFcn(app, @YSlidercoronalValueChanged, true);
            app.YSlidercoronal.ValueChangingFcn = createCallbackFcn(app, @YSlidercoronalValueChanging, true);
            app.YSlidercoronal.Value = 117;
            app.XSlider_2Label = uilabel(app.UIFigure);
            app.XSlider_2Label.Text = {'X'; ''};
            app.XSlidersagittal = uislider(app.UIFigure);
            app.XSlidersagittal.Limits = [0 468];
            app.XSlidersagittal.ValueChangedFcn = createCallbackFcn(app, @XSlidersagittalValueChanged, true);
            app.XSlidersagittal.Value = 234;
            app.YSlider_2Label = uilabel(app.UIFigure);
            app.YSlider_2Label.Text = {'Y'; ''};
            app.YSlidersagittal = uislider(app.UIFigure);
            app.YSlidersagittal.Limits = [0 234];
            app.YSlidersagittal.ValueChangedFcn = createCallbackFcn(app, @YSlidersagittalValueChanged, true);
            app.YSlidersagittal.Value = 117;
            app.Line = uibutton(app.UIFigure, 'push');
            app.Line.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.Line.Text = 'Line';
            app.Polygon = uibutton(app.UIFigure, 'push');
            app.Polygon.ButtonPushedFcn = createCallbackFcn(app, @PolygonButtonPushed, true);
            app.Polygon.Text = 'Polygon';
            app.Elipse = uibutton(app.UIFigure, 'push');
            app.Elipse.ButtonPushedFcn = createCallbackFcn(app, @ElipseButtonPushed, true);
            app.Elipse.Text = 'Elipse';
            app.Angle = uibutton(app.UIFigure, 'push');
            app.Angle.ButtonPushedFcn = createCallbackFcn(app, @AngleButtonPushed, true);
            app.Angle.Text = 'Angle';
            app.LengthEditField = uieditfield(app.UIFigure, 'text');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Length:';
            app.Axial = uiaxes(app.UIFigure);
            title(app.Axial, 'Axial')
            app.Sagittal = uiaxes(app.UIFigure);
            title(app.Sagittal, 'Sagittal')
            zlabel(app.Sagittal, 'Z')
            app.Coronal = uiaxes(app.UIFigure);
            title(app.Coronal, 'Coronal')
            app.oblique = uiaxes(app.UIFigure);
            title(app.oblique, 'Oblique')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModalitiesProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end